classdef MST_v_1_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ModelAnalysisPanel          matlab.ui.container.Panel
        MA_Result_Button            matlab.ui.control.Button
        MA_Reset_Button             matlab.ui.control.Button
        MA_Analysis_Button          matlab.ui.control.Button
        MA_Find_Button              matlab.ui.control.Button
        MA_ModelPath_TextBox        matlab.ui.control.TextArea
        Label_11                    matlab.ui.control.Label
        MA_HOME_Button              matlab.ui.control.Button
        ModelingGuidePanel          matlab.ui.container.Panel
        MG_Result_Button            matlab.ui.control.Button
        MG_Check_Button             matlab.ui.control.Button
        MG_Language_TextBox         matlab.ui.control.EditField
        Label_10                    matlab.ui.control.Label
        MG_Solver_TextBox           matlab.ui.control.EditField
        Label_9                     matlab.ui.control.Label
        MG_Junction_TextBox         matlab.ui.control.EditField
        Label_8                     matlab.ui.control.Label
        MG_TransitionColor_TextBox  matlab.ui.control.EditField
        Label_7                     matlab.ui.control.Label
        MG_BGColor_TextBox          matlab.ui.control.EditField
        Label_6                     matlab.ui.control.Label
        MG_ModelList_List           matlab.ui.control.ListBox
        Label_5                     matlab.ui.control.Label
        MG_Reset_Button             matlab.ui.control.Button
        MG_Load_Button              matlab.ui.control.Button
        MG_Find_Button              matlab.ui.control.Button
        MG_ModelPath_TextBox        matlab.ui.control.TextArea
        Label_4                     matlab.ui.control.Label
        MG_HOME_Button              matlab.ui.control.Button
        SignalModifyPanel           matlab.ui.container.Panel
        SM_Reset_Button             matlab.ui.control.Button
        SM_ModelCreate_Button       matlab.ui.control.Button
        SM_Delette_Button           matlab.ui.control.Button
        SM_Add_Button               matlab.ui.control.Button
        SM_Modify_Button            matlab.ui.control.Button
        SM_Signal_Tree              matlab.ui.container.Tree
        SignalTree_Input            matlab.ui.container.TreeNode
        Node                        matlab.ui.container.TreeNode
        Node_2                      matlab.ui.container.TreeNode
        SignalTree_Output           matlab.ui.container.TreeNode
        Output_LIN_CommandNode      matlab.ui.container.TreeNode
        Node_3                      matlab.ui.container.TreeNode
        SignalTree_Parameter        matlab.ui.container.TreeNode
        Par_TimerCountNode          matlab.ui.container.TreeNode
        Node_4                      matlab.ui.container.TreeNode
        SM_Load_Button              matlab.ui.control.Button
        SM_Find_Button              matlab.ui.control.Button
        SM_ModelPath_TextBox        matlab.ui.control.TextArea
        TextArea_4Label             matlab.ui.control.Label
        SM_HOME_Button              matlab.ui.control.Button
        InitTCPanel                 matlab.ui.container.Panel
        IT_Output_TextArea          matlab.ui.control.TextArea
        Label_13                    matlab.ui.control.Label
        IT_Input_TextArea           matlab.ui.control.TextArea
        Label_12                    matlab.ui.control.Label
        IT_PathOpen_Button          matlab.ui.control.Button
        IT_Reset_Button             matlab.ui.control.Button
        IT_Option_Group             matlab.ui.container.ButtonGroup
        IT_Option_NoneREQ           matlab.ui.control.RadioButton
        IT_Option_REQ               matlab.ui.control.RadioButton
        IT_TCCreate_Button          matlab.ui.control.Button
        IT_Find_Button              matlab.ui.control.Button
        IT_ModelPath_TextBox        matlab.ui.control.TextArea
        Label_3                     matlab.ui.control.Label
        IT_HOME_Button              matlab.ui.control.Button
        TransitionPanel             matlab.ui.container.Panel
        TP_Reset_Button             matlab.ui.control.Button
        TP_Transfer_Button          matlab.ui.control.Button
        TP_Result_TextBox           matlab.ui.control.TextArea
        Label_2                     matlab.ui.control.Label
        TP_Model_TextBox            matlab.ui.control.TextArea
        Label                       matlab.ui.control.Label
        TP_HOME_Button              matlab.ui.control.Button
        HOMEPanel                   matlab.ui.container.Panel
        Copyright2022KYGSJJAllrightreservedLabel  matlab.ui.control.Label
        SimpleVerify_Button         matlab.ui.control.Button
        TimeSetting_Button          matlab.ui.control.Button
        WebView_Button              matlab.ui.control.Button
        TempleteCreate_Button       matlab.ui.control.Button
        SpecModelCreate_Button      matlab.ui.control.Button
        ModelAnalysis_Button        matlab.ui.control.Button
        ModelingGuide_Button        matlab.ui.control.Button
        SignalModify_Button         matlab.ui.control.Button
        InitTC_Button               matlab.ui.control.Button
        Transition_Button           matlab.ui.control.Button
    end

    
    properties (Access = private)
        Model_FileName % 모델 파일명
        Model_PathName % 모델 파일 경로명
        Model_ModelName % 모델명
        Transition % 천이 변환 기능에 사용되는 모델 기준 천이 조건식 정보
    end
    
    methods (Access = public)
        
        function results = GetSignalName(app)
            pathName = app.Model_PathName;

            if app.Model_FileName ~= 0
                addpath(pathName);
                open_system(app.Model_FileName); % 모델 파일을 열음으로 find_system 함수 작동 가능 (열지 않으면 사용 불가)
                SelectmodelFile = erase(app.Model_FileName,'.slx');
                % Input Signal
                Inports = find_system(SelectmodelFile, 'SearchDepth', '1', 'BlockType','Inport');
                Inports = erase(Inports, {SelectmodelFile,'/'}); %Inputs
                % Output Signal
                Outports = find_system(SelectmodelFile, 'SearchDepth', '1', 'BlockType','Outport');
                Outports = erase(Outports, {SelectmodelFile,'/'}); %Outputs

                fclose('all');
                close_system(app.Model_FileName);

                % Input, Output TextBox Update
                app.IT_Input_TextArea.Value = Inports;
                app.IT_Output_TextArea.Value = Outports;
                rmpath(pathName);

                results = 1;
            else
                results = 0;
            end
            
        end
        
        % 천이 변환 기능에서 사용하는 함수
        function result_print = Transition_Index(app)
            Transition_Allchar=char(app.Transition);
            if Transition_Allchar(1) == '['
                Transition_char = erase(string(Transition_Allchar),"[");
                Transition_char = erase(string(Transition_char),"]");
            end
            % '('로 시작될 경우
            % function Transition_Index_Start()을 사용
            if Transition_char(1)=='('
                result_print=Transition_Index_InStart(app,Transition_char);
            % '('로 시작되지 않을 경우
            %Transition_Index_InNotStart()을 사용
            else
                result_print=Transition_Index_InNotStart(app,Transition_char);
            end
        end
    end
    
    methods (Access = private)
        
        %'('로 시작하는 Transition
        function result_print_InStart=Transition_Index_InStart(app,Transition)
        
            Transition_size=size(Transition,2);
        
            Transition_char=char(Transition);
        
            %괄호갯수 count
            bracket_count=count(Transition,'(');
        
            %괄호갯수에 따라 '(' 와 ')' 인덱스
            bracket_count_index_in=1;
            bracket_count_index_out=bracket_count;
        
            result='';
        
        
            %Transition 시작부터 괄호가 있는 경우
            for i=1:Transition_size
        
                now_char=Transition_char(i);
                next_char='';
                
                if i<Transition_size
                    next_char=Transition_char(i+1);
                end
                
                % ( 부분
                % Use bracket_count_index_in
                if now_char=='('
                    %첫번째 괄호부터
                    if bracket_count_index_in==1
        
                        result=append(result,now_char);
                        result=append(result,'\n');%
                        %result=append(result,string(bracket_count_index_in)); %띄어쓰기 갯수 파악
                        result=append(result,char(tab_counter(app,bracket_count_index_in))); %tab
        
                        bracket_count_index_in=bracket_count_index_in+1;
                    else
                        result=append(result,'\n');
                        %result=append(result,string(bracket_count_index_in)); %띄어쓰기 갯수 파악
                        result=append(result,char(tab_counter(app,bracket_count_index_in))); %tab
                        result=append(result,now_char);
        
                        bracket_count_index_in=bracket_count_index_in+1;
                    end
        
                % ) 부분
                % Use bracket_count_index_out
                elseif now_char==')'
                    %첫번째 ) 일 경우
                        
                    if bracket_count_index_out==bracket_count
                        result=append(result,now_char);
        
                        bracket_count_index_out=bracket_count_index_out-1;
                    %괄호 끝일경우
                    elseif bracket_count_index_out==1
                        result=append(result,'\n');
                        result=append(result,now_char);
                    %다음글자가 ')' 일 경우
                    elseif next_char==')'
                        result=append(result,now_char);
                        
                        bracket_count_index_out=bracket_count_index_out-1;
            
                    %그 외의 )
                    else
                        result=append(result,'\n');
                        result=append(result,char(tab_counter(app,bracket_count_index_out))); %tab
                        %result=append(result,string(bracket_count_index_out)); %띄어쓰기 갯수 파악
                        result=append(result,now_char);
                        bracket_count_index_out=bracket_count_index_out-1;
                    end
                else 
                    result=append(result,now_char);
                end
            end
            result_print_InStart=sprintf(result);
        end
        %'('로 시작하지 않은 Transition
        function result_print_InNotStart=Transition_Index_InNotStart(app,Transition)
            %Transition_size=size(Transition,2);
            Transition_size=size(Transition,1);
            Transition_char=char(Transition);
            %괄호갯수 count
            bracket_count=count(Transition,'(');
            %괄호갯수에 따라 '(' 와 ')' 인덱스
            bracket_count_index_in=1;
            bracket_count_index_out=bracket_count;
            result='';
            %Transition 시작부터 괄호가 없는경우
            for j=1:Transition_size
                now_char=Transition_char(j);
                next_char='';
                if j<Transition_size
                    next_char=Transition_char(j+1);
                end
                % ( 부분
                % Use bracket_count_index_in
                if now_char=='('
        
                    result=append(result,'\n');
                    %result=append(result,string(bracket_count_index_in)); %띄어쓰기 갯수 파악
                    result=append(result,char(tab_counter(app,bracket_count_index_in))); %tab
                    result=append(result,now_char);
        
                    bracket_count_index_in=bracket_count_index_in+1;
        
        
                % ) 부분
                % Use bracket_count_index_out
                elseif now_char==')'
                    %첫번째 ) 일 경우
                    if bracket_count_index_out==bracket_count
                        result=append(result,now_char);
        
                        bracket_count_index_out=bracket_count_index_out-1;
                    %다음글자가 ')' 일 경우
                    elseif next_char==')'
                        result=append(result,now_char);
                        
                        bracket_count_index_out=bracket_count_index_out-1;
                    %그 외의 )
                    else
                        result=append(result,'\n');
                        result=append(result,char(tab_counter(app,bracket_count_index_out))); %tab
                        %result=append(result,string(bracket_count_index_out)); %띄어쓰기 갯수 파악
                        result=append(result,now_char);
        
                        bracket_count_index_out=bracket_count_index_out-1;
                    end
                else 
                    result=append(result,now_char);
                end
            end
            result_print_InNotStart=sprintf(result);
        end
        
        function taab=tab_counter(~,tab_number)
            taab='';
            for tab_i=1:(tab_number)
                taab=append(taab,'\t');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Transition_Button
        function Transition_ButtonPushed(app, event)
            app.HOMEPanel.Position = [-673 -293 658 281];
            app.TransitionPanel.Position = [1 1 658 278];
        end

        % Button pushed function: TP_HOME_Button
        function TP_HOME_ButtonPushed(app, event)
            app.HOMEPanel.Position = [1 1 658 278];
            app.TransitionPanel.Position = [-673 -293 658 281];
        end

        % Button pushed function: InitTC_Button
        function InitTC_ButtonPushed(app, event)
            app.HOMEPanel.Position = [1 -293 658 281];
            app.InitTCPanel.Position = [1 1 658 278];
        end

        % Button pushed function: IT_HOME_Button
        function IT_HOME_ButtonPushed(app, event)
            app.HOMEPanel.Position = [1 1 658 278];
            app.InitTCPanel.Position = [1 -293 658 281];
        end

        % Button pushed function: SignalModify_Button
        function SignalModify_ButtonPushed(app, event)
            app.HOMEPanel.Position = [-673 -590 658 281];
            app.SignalModifyPanel.Position = [1 1 658 278];
            expand(app.SM_Signal_Tree);
        end

        % Button pushed function: SM_HOME_Button
        function SM_HOME_ButtonPushed(app, event)
            app.HOMEPanel.Position = [1 1 658 278];
            app.SignalModifyPanel.Position = [-673 -590 658 281];
        end

        % Button pushed function: ModelingGuide_Button
        function ModelingGuide_ButtonPushed(app, event)
            app.HOMEPanel.Position = [1 -590 658 281];
            app.ModelingGuidePanel.Position = [1 1 658 278];
        end

        % Button pushed function: MG_HOME_Button
        function MG_HOME_ButtonPushed(app, event)
            app.HOMEPanel.Position = [1 1 658 278];
            app.ModelingGuidePanel.Position = [1 -590 658 281];
        end

        % Button pushed function: ModelAnalysis_Button
        function ModelAnalysis_ButtonPushed(app, event)
            app.HOMEPanel.Position = [670 -293 658 281];
            app.ModelAnalysisPanel.Position = [1 1 658 278];
        end

        % Button pushed function: MA_HOME_Button
        function MA_HOME_ButtonPushed(app, event)
            app.HOMEPanel.Position = [1 1 658 278];
            app.ModelAnalysisPanel.Position = [670 -293 658 281];
        end

        % Button pushed function: SpecModelCreate_Button
        function SpecModelCreate_ButtonPushed(app, event)
            message = {'어이쿠 손이 미끄러진거 아냐?','22년 하반기에는 개발할 계획이니 참고 조금 기다려줘'};
            uialert(app.UIFigure,message,'Warning','Icon','warning');
        end

        % Button pushed function: TempleteCreate_Button
        function TempleteCreate_ButtonPushed(app, event)
            message = {'어이쿠 손이 미끄러진거 아냐?','22년 하반기에는 개발할 계획이니 참고 조금 기다려줘'};
            uialert(app.UIFigure,message,'Warning','Icon','warning');
        end

        % Button pushed function: WebView_Button
        function WebView_ButtonPushed(app, event)
            message = {'어이쿠 손이 미끄러진거 아냐?','22년 하반기에는 개발할 계획이니 참고 조금 기다려줘'};
            uialert(app.UIFigure,message,'Warning','Icon','warning');
        end

        % Button pushed function: TimeSetting_Button
        function TimeSetting_ButtonPushed(app, event)
            message = {'어이쿠 손이 미끄러진거 아냐?','22년 하반기에는 개발할 계획이니 참고 조금 기다려줘'};
            uialert(app.UIFigure,message,'Warning','Icon','warning');
        end

        % Button pushed function: SimpleVerify_Button
        function SimpleVerify_ButtonPushed(app, event)
            message = {'어이쿠 손이 미끄러진거 아냐?','22년 하반기에는 개발할 계획이니 참고 조금 기다려줘'};
            uialert(app.UIFigure,message,'Warning','Icon','warning');
        end

        % Button pushed function: TP_Reset_Button
        function TP_Reset_ButtonPushed(app, event)
            % 천이 분석 기능 Reset 버튼 시 TextBox 초기화
            app.TP_Model_TextBox.Value = '';
            app.TP_Result_TextBox.Value = '';
        end

        % Button pushed function: IT_Reset_Button
        function IT_Reset_ButtonPushed(app, event)
            % Init TC 생성 기능 Reset 버튼 시 TextBox, Group 초기화
            app.IT_ModelPath_TextBox.Value = '';
            app.IT_Option_Group.SelectedObject = app.IT_Option_REQ;
            app.IT_TCCreate_Button.Enable = 'Off';
            app.IT_PathOpen_Button.Enable = 'Off';
            app.IT_Input_TextArea.Value = '';
            app.IT_Output_TextArea.Value = '';
            app.Model_PathName = '';
            app.Model_FileName = '';
        end

        % Button pushed function: SM_Reset_Button
        function SM_Reset_ButtonPushed(app, event)
            % 신호 및 파라미터 수정 기능 Reset 버튼 시 TextBox, Tree 초기화
            app.SM_ModelPath_TextBox.Value = '';
            app.SignalTree_Input.Children.delete;
            app.SignalTree_Output.Children.delete;
            app.SignalTree_Parameter.Children.delete;
            app.Model_PathName = '';
            app.Model_FileName = '';
        end

        % Button pushed function: MG_Reset_Button
        function MG_Reset_ButtonPushed(app, event)
            % 모델링 가이드 체크 기능 Reset 버튼 시 TextBox, List 초기화
            app.MG_ModelPath_TextBox.Value = '';
            app.MG_ModelList_List.Items = {''};
        end

        % Button pushed function: MA_Reset_Button
        function MA_Reset_ButtonPushed(app, event)
            % 모델 분석 기능 Reset 버튼 
            app.MA_ModelPath_TextBox.Value = '';
        end

        % Button pushed function: TP_Transfer_Button
        function TP_Transfer_ButtonPushed(app, event)
            % 천이 변환 기능에서 "변환" 버튼 동작
            app.Transition = app.TP_Model_TextBox.Value;
            if app.Transition == ""
                message = {'모델 천이 조건식이 비워져 있습니다.','천이 조건식을 올바르게 작성해주세요'};
                uialert(app.UIFigure,message,'Warning',...
                'Icon','warning');
            else
                app.TP_Result_TextBox.Value = app.Transition_Index();
                figure(app.UIFigure); % 프로그램 UI Focus ON
            end
        end

        % Button pushed function: IT_Find_Button
        function IT_Find_ButtonPushed(app, event)
            % Init TC 생성 기능 '찾기' 버튼 동작
            [app.Model_FileName, app.Model_PathName,] = uigetfile({'*.slx;*.mdl'}, 'Select the Model File');
            if app.Model_FileName == 0
                return;
            end
            ModelFileName = string(app.Model_PathName) + string(app.Model_FileName);
            app.IT_ModelPath_TextBox.Value = ModelFileName;
            app.IT_TCCreate_Button.Enable = 'On';
            
            % 모델 파일 내 입력 신호, 출력 신호 포트 정보 확인하여 불러오기
            if GetSignalName(app)
                figure(app.UIFigure); % 프로그램 UI Focus ON
                uialert(app.UIFigure,'Model Load Complete!','Complete','Icon','success'); % Model Load 완료 Alert
            else
                message = {'모델 파일의 입/출력 신호 정보를 불러올 수 없습니다.','올바른 모델 파일인지 확인 해주세요.'};
                uialert(app.UIFigure,message,'Warning','Icon','warning');
            end
        end

        % Button pushed function: IT_TCCreate_Button
        function IT_TCCreate_ButtonPushed(app, event)
            % Init TC 생성 기능 'TC 생성' 버튼 동작
            app.IT_PathOpen_Button.Enable = 'On';
            
            % Req 존재/미존재 판단하는 로직 ReqOptionFlag로 사용하면 됨
            if app.IT_Option_REQ.Value == 1
                ReqOptionFlag = 1;
            elseif app.IT_Option_NoneREQ == 1
                ReqOptionFlag = 0;
            end
            
            % 모델 파일의 입력 신호, 출력 신호 도출해오기
           
        end

        % Button pushed function: IT_PathOpen_Button
        function IT_PathOpen_ButtonPushed(app, event)
            % 천이 변환 기능에서 "경로 열기" 버튼 동작
        end

        % Button pushed function: SM_Find_Button
        function SM_Find_ButtonPushed(app, event)
            % 신호 및 파라미터 수정 기능에서 "찾기" 버튼 동작
        end

        % Button pushed function: SM_Load_Button
        function SM_Load_ButtonPushed(app, event)
            % 신호 및 파라미터 수정 기능에서 "Load" 버튼 동작
        end

        % Button pushed function: SM_Modify_Button
        function SM_Modify_ButtonPushed(app, event)
            % 신호 및 파라미터 수정 기능에서 "수정" 버튼 동작
        end

        % Button pushed function: SM_Add_Button
        function SM_Add_ButtonPushed(app, event)
            % 신호 및 파라미터 수정 기능에서 "추가" 버튼 동작
        end

        % Button pushed function: SM_Delette_Button
        function SM_Delette_ButtonPushed(app, event)
            % 신호 및 파라미터 수정 기능에서 "삭제" 버튼 동작
        end

        % Button pushed function: SM_ModelCreate_Button
        function SM_ModelCreate_ButtonPushed(app, event)
            % 신호 및 파라미터 수정 기능에서 "모델생성" 버튼 동작
        end

        % Button pushed function: MG_Find_Button
        function MG_Find_ButtonPushed(app, event)
            % 모델링 가이드 체크 기능에서 "찾기" 버튼 동작
        end

        % Button pushed function: MG_Load_Button
        function MG_Load_ButtonPushed(app, event)
            % 모델링 가이드 체크 기능에서 "Load" 버튼 동작
        end

        % Button pushed function: MG_Check_Button
        function MG_Check_ButtonPushed(app, event)
            % 모델링 가이드 체크 기능에서 "모델링가이드 체크" 버튼 동작
        end

        % Button pushed function: MG_Result_Button
        function MG_Result_ButtonPushed(app, event)
            % 모델링 가이드 체크 기능에서 "결과 열기" 버튼 동작
        end

        % Button pushed function: MA_Find_Button
        function MA_Find_ButtonPushed(app, event)
            % 모델 분석 기능에서 "찾기" 버튼 동작
        end

        % Button pushed function: MA_Analysis_Button
        function MA_Analysis_ButtonPushed(app, event)
            % 모델 분석 기능에서 "분석" 버튼 동작
        end

        % Button pushed function: MA_Result_Button
        function MA_Result_ButtonPushed(app, event)
            % 모델 분석 기능에서 "분석 결과 확인" 버튼 동작
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HOMEPanel = uipanel(app.UIFigure);
            app.HOMEPanel.Title = 'HOME';
            app.Transition_Button = uibutton(app.HOMEPanel, 'push');
            app.Transition_Button.ButtonPushedFcn = createCallbackFcn(app, @Transition_ButtonPushed, true);
            app.Transition_Button.Text = '천이 변환';
            app.InitTC_Button = uibutton(app.HOMEPanel, 'push');
            app.InitTC_Button.ButtonPushedFcn = createCallbackFcn(app, @InitTC_ButtonPushed, true);
            app.InitTC_Button.Text = 'Init TC 생성';
            app.SignalModify_Button = uibutton(app.HOMEPanel, 'push');
            app.SignalModify_Button.ButtonPushedFcn = createCallbackFcn(app, @SignalModify_ButtonPushed, true);
            app.SignalModify_Button.Text = {'신호 및 파라미터'; '수정'};
            app.ModelingGuide_Button = uibutton(app.HOMEPanel, 'push');
            app.ModelingGuide_Button.ButtonPushedFcn = createCallbackFcn(app, @ModelingGuide_ButtonPushed, true);
            app.ModelingGuide_Button.Text = '모델링 가이드 체크';
            app.ModelAnalysis_Button = uibutton(app.HOMEPanel, 'push');
            app.ModelAnalysis_Button.ButtonPushedFcn = createCallbackFcn(app, @ModelAnalysis_ButtonPushed, true);
            app.ModelAnalysis_Button.Text = '모델 분석';
            app.SpecModelCreate_Button = uibutton(app.HOMEPanel, 'push');
            app.SpecModelCreate_Button.ButtonPushedFcn = createCallbackFcn(app, @SpecModelCreate_ButtonPushed, true);
            app.SpecModelCreate_Button.Text = {'사양 기반'; '모델 생성'};
            app.TempleteCreate_Button = uibutton(app.HOMEPanel, 'push');
            app.TempleteCreate_Button.ButtonPushedFcn = createCallbackFcn(app, @TempleteCreate_ButtonPushed, true);
            app.TempleteCreate_Button.Text = {'모델 템플릿'; '생성'};
            app.WebView_Button = uibutton(app.HOMEPanel, 'push');
            app.WebView_Button.ButtonPushedFcn = createCallbackFcn(app, @WebView_ButtonPushed, true);
            app.WebView_Button.Text = {'WebView'; '일괄 생성'};
            app.TimeSetting_Button = uibutton(app.HOMEPanel, 'push');
            app.TimeSetting_Button.ButtonPushedFcn = createCallbackFcn(app, @TimeSetting_ButtonPushed, true);
            app.TimeSetting_Button.Text = {'검증 시간'; '조절'};
            app.SimpleVerify_Button = uibutton(app.HOMEPanel, 'push');
            app.SimpleVerify_Button.ButtonPushedFcn = createCallbackFcn(app, @SimpleVerify_ButtonPushed, true);
            app.SimpleVerify_Button.Text = '심플 검증';
            app.Copyright2022KYGSJJAllrightreservedLabel = uilabel(app.HOMEPanel);
            app.Copyright2022KYGSJJAllrightreservedLabel.Text = 'Copyright 2022. KYG,SJJ All right reserved.';
            app.TransitionPanel = uipanel(app.UIFigure);
            app.TransitionPanel.Title = '천이 변환';
            app.TP_HOME_Button = uibutton(app.TransitionPanel, 'push');
            app.TP_HOME_Button.ButtonPushedFcn = createCallbackFcn(app, @TP_HOME_ButtonPushed, true);
            app.TP_HOME_Button.Text = 'HOME';
            app.Label = uilabel(app.TransitionPanel);
            app.Label.Text = '모델 조건식  :';
            app.TP_Model_TextBox = uitextarea(app.TransitionPanel);
            app.Label_2 = uilabel(app.TransitionPanel);
            app.Label_2.Text = '변환 조건식  :';
            app.TP_Result_TextBox = uitextarea(app.TransitionPanel);
            app.TP_Transfer_Button = uibutton(app.TransitionPanel, 'push');
            app.TP_Transfer_Button.ButtonPushedFcn = createCallbackFcn(app, @TP_Transfer_ButtonPushed, true);
            app.TP_Transfer_Button.Text = '변환';
            app.TP_Reset_Button = uibutton(app.TransitionPanel, 'push');
            app.TP_Reset_Button.ButtonPushedFcn = createCallbackFcn(app, @TP_Reset_ButtonPushed, true);
            app.TP_Reset_Button.Text = 'Reset';
            app.InitTCPanel = uipanel(app.UIFigure);
            app.InitTCPanel.Title = 'Init TC 생성';
            app.IT_HOME_Button = uibutton(app.InitTCPanel, 'push');
            app.IT_HOME_Button.ButtonPushedFcn = createCallbackFcn(app, @IT_HOME_ButtonPushed, true);
            app.IT_HOME_Button.Text = 'HOME';
            app.Label_3 = uilabel(app.InitTCPanel);
            app.Label_3.Text = '모델 경로 :';
            app.IT_ModelPath_TextBox = uitextarea(app.InitTCPanel);
            app.IT_Find_Button = uibutton(app.InitTCPanel, 'push');
            app.IT_Find_Button.ButtonPushedFcn = createCallbackFcn(app, @IT_Find_ButtonPushed, true);
            app.IT_Find_Button.Text = '찾기';
            app.IT_TCCreate_Button = uibutton(app.InitTCPanel, 'push');
            app.IT_TCCreate_Button.ButtonPushedFcn = createCallbackFcn(app, @IT_TCCreate_ButtonPushed, true);
            app.IT_TCCreate_Button.Enable = 'off';
            app.IT_TCCreate_Button.Text = 'TC 생성';
            app.IT_Option_Group = uibuttongroup(app.InitTCPanel);
            app.IT_Option_Group.Title = 'TC 생성 옵션';
            app.IT_Option_REQ = uiradiobutton(app.IT_Option_Group);
            app.IT_Option_REQ.Text = 'REQ 존재';
            app.IT_Option_REQ.Value = true;
            app.IT_Option_NoneREQ = uiradiobutton(app.IT_Option_Group);
            app.IT_Option_NoneREQ.Text = 'REQ 미존재';
            app.IT_Reset_Button = uibutton(app.InitTCPanel, 'push');
            app.IT_Reset_Button.ButtonPushedFcn = createCallbackFcn(app, @IT_Reset_ButtonPushed, true);
            app.IT_Reset_Button.Text = 'Reset';
            app.IT_PathOpen_Button = uibutton(app.InitTCPanel, 'push');
            app.IT_PathOpen_Button.ButtonPushedFcn = createCallbackFcn(app, @IT_PathOpen_ButtonPushed, true);
            app.IT_PathOpen_Button.Enable = 'off';
            app.IT_PathOpen_Button.Text = '경로 열기';
            app.Label_12 = uilabel(app.InitTCPanel);
            app.Label_12.Text = '입력 신호';
            app.IT_Input_TextArea = uitextarea(app.InitTCPanel);
            app.Label_13 = uilabel(app.InitTCPanel);
            app.Label_13.Text = '출력 신호';
            app.IT_Output_TextArea = uitextarea(app.InitTCPanel);
            app.SignalModifyPanel = uipanel(app.UIFigure);
            app.SignalModifyPanel.Title = '신호 및 파라미터 수정';
            app.SM_HOME_Button = uibutton(app.SignalModifyPanel, 'push');
            app.SM_HOME_Button.ButtonPushedFcn = createCallbackFcn(app, @SM_HOME_ButtonPushed, true);
            app.SM_HOME_Button.Text = 'HOME';
            app.TextArea_4Label = uilabel(app.SignalModifyPanel);
            app.TextArea_4Label.Text = '모델 :';
            app.SM_ModelPath_TextBox = uitextarea(app.SignalModifyPanel);
            app.SM_Find_Button = uibutton(app.SignalModifyPanel, 'push');
            app.SM_Find_Button.ButtonPushedFcn = createCallbackFcn(app, @SM_Find_ButtonPushed, true);
            app.SM_Find_Button.Text = '찾기';
            app.SM_Load_Button = uibutton(app.SignalModifyPanel, 'push');
            app.SM_Load_Button.ButtonPushedFcn = createCallbackFcn(app, @SM_Load_ButtonPushed, true);
            app.SM_Load_Button.Text = 'Load';
            app.SM_Signal_Tree = uitree(app.SignalModifyPanel);
            app.SignalTree_Input = uitreenode(app.SM_Signal_Tree);
            app.SignalTree_Input.Text = '입력신호';
            app.Node = uitreenode(app.SignalTree_Input);
            app.Node.Text = 'Input_CAN_Status';
            app.Node_2 = uitreenode(app.SignalTree_Input);
            app.Node_2.Text = 'Input_HW_Switch';
            app.SignalTree_Output = uitreenode(app.SM_Signal_Tree);
            app.SignalTree_Output.Text = '출력신호';
            app.Output_LIN_CommandNode = uitreenode(app.SignalTree_Output);
            app.Output_LIN_CommandNode.Text = 'Output_LIN_Command';
            app.Node_3 = uitreenode(app.SignalTree_Output);
            app.Node_3.Text = 'Output_b_Request';
            app.SignalTree_Parameter = uitreenode(app.SM_Signal_Tree);
            app.SignalTree_Parameter.Text = '파라미터';
            app.Par_TimerCountNode = uitreenode(app.SignalTree_Parameter);
            app.Par_TimerCountNode.Text = 'Par_TimerCount';
            app.Node_4 = uitreenode(app.SignalTree_Parameter);
            app.Node_4.Text = 'Calib_Profile';
            app.SM_Modify_Button = uibutton(app.SignalModifyPanel, 'push');
            app.SM_Modify_Button.ButtonPushedFcn = createCallbackFcn(app, @SM_Modify_ButtonPushed, true);
            app.SM_Modify_Button.Text = '수정';
            app.SM_Add_Button = uibutton(app.SignalModifyPanel, 'push');
            app.SM_Add_Button.ButtonPushedFcn = createCallbackFcn(app, @SM_Add_ButtonPushed, true);
            app.SM_Add_Button.Text = '추가';
            app.SM_Delette_Button = uibutton(app.SignalModifyPanel, 'push');
            app.SM_Delette_Button.ButtonPushedFcn = createCallbackFcn(app, @SM_Delette_ButtonPushed, true);
            app.SM_Delette_Button.Text = '삭제';
            app.SM_ModelCreate_Button = uibutton(app.SignalModifyPanel, 'push');
            app.SM_ModelCreate_Button.ButtonPushedFcn = createCallbackFcn(app, @SM_ModelCreate_ButtonPushed, true);
            app.SM_ModelCreate_Button.Text = '모델 생성';
            app.SM_Reset_Button = uibutton(app.SignalModifyPanel, 'push');
            app.SM_Reset_Button.ButtonPushedFcn = createCallbackFcn(app, @SM_Reset_ButtonPushed, true);
            app.SM_Reset_Button.Text = 'Reset';
            app.ModelingGuidePanel = uipanel(app.UIFigure);
            app.ModelingGuidePanel.Title = '모델링 가이드 체크';
            app.MG_HOME_Button = uibutton(app.ModelingGuidePanel, 'push');
            app.MG_HOME_Button.ButtonPushedFcn = createCallbackFcn(app, @MG_HOME_ButtonPushed, true);
            app.MG_HOME_Button.Text = 'HOME';
            app.Label_4 = uilabel(app.ModelingGuidePanel);
            app.Label_4.Text = '모델 폴더 :';
            app.MG_ModelPath_TextBox = uitextarea(app.ModelingGuidePanel);
            app.MG_Find_Button = uibutton(app.ModelingGuidePanel, 'push');
            app.MG_Find_Button.ButtonPushedFcn = createCallbackFcn(app, @MG_Find_ButtonPushed, true);
            app.MG_Find_Button.Text = '찾기';
            app.MG_Load_Button = uibutton(app.ModelingGuidePanel, 'push');
            app.MG_Load_Button.ButtonPushedFcn = createCallbackFcn(app, @MG_Load_ButtonPushed, true);
            app.MG_Load_Button.Text = 'Load';
            app.MG_Reset_Button = uibutton(app.ModelingGuidePanel, 'push');
            app.MG_Reset_Button.ButtonPushedFcn = createCallbackFcn(app, @MG_Reset_ButtonPushed, true);
            app.MG_Reset_Button.Text = 'Reset';
            app.Label_5 = uilabel(app.ModelingGuidePanel);
            app.Label_5.Text = '모델 파일';
            app.MG_ModelList_List = uilistbox(app.ModelingGuidePanel);
            app.MG_ModelList_List.Items = {'BDC_DoorLockUnlock_T1_v_1_8.slx', 'BDC_Flasher_T2_v_2_3.slx', 'BDC_Warning_T5_v_3_1.slx', 'BDC_PowerWindow_T1_v_1_3.slx'};
            app.MG_ModelList_List.Value = 'BDC_DoorLockUnlock_T1_v_1_8.slx';
            app.Label_6 = uilabel(app.ModelingGuidePanel);
            app.Label_6.Text = '배경 색상';
            app.MG_BGColor_TextBox = uieditfield(app.ModelingGuidePanel, 'text');
            app.MG_BGColor_TextBox.Value = 'fffcec';
            app.Label_7 = uilabel(app.ModelingGuidePanel);
            app.Label_7.Text = '천이색';
            app.MG_TransitionColor_TextBox = uieditfield(app.ModelingGuidePanel, 'text');
            app.MG_TransitionColor_TextBox.Value = '528bc5';
            app.Label_8 = uilabel(app.ModelingGuidePanel);
            app.Label_8.Text = '정션 색';
            app.MG_Junction_TextBox = uieditfield(app.ModelingGuidePanel, 'text');
            app.MG_Junction_TextBox.Value = 'c64f00';
            app.Label_9 = uilabel(app.ModelingGuidePanel);
            app.Label_9.Text = '솔버(Solver)';
            app.MG_Solver_TextBox = uieditfield(app.ModelingGuidePanel, 'text');
            app.MG_Solver_TextBox.Value = 'dT';
            app.Label_10 = uilabel(app.ModelingGuidePanel);
            app.Label_10.Text = '구동 언어';
            app.MG_Language_TextBox = uieditfield(app.ModelingGuidePanel, 'text');
            app.MG_Language_TextBox.Value = 'C';
            app.MG_Check_Button = uibutton(app.ModelingGuidePanel, 'push');
            app.MG_Check_Button.ButtonPushedFcn = createCallbackFcn(app, @MG_Check_ButtonPushed, true);
            app.MG_Check_Button.Text = {'모델링가이드'; '체크'};
            app.MG_Result_Button = uibutton(app.ModelingGuidePanel, 'push');
            app.MG_Result_Button.ButtonPushedFcn = createCallbackFcn(app, @MG_Result_ButtonPushed, true);
            app.MG_Result_Button.Text = '결과 열기';
            app.ModelAnalysisPanel = uipanel(app.UIFigure);
            app.ModelAnalysisPanel.Title = '모델 분석';
            app.MA_HOME_Button = uibutton(app.ModelAnalysisPanel, 'push');
            app.MA_HOME_Button.ButtonPushedFcn = createCallbackFcn(app, @MA_HOME_ButtonPushed, true);
            app.MA_HOME_Button.Text = 'HOME';
            app.Label_11 = uilabel(app.ModelAnalysisPanel);
            app.Label_11.Text = '모델 경로 :';
            app.MA_ModelPath_TextBox = uitextarea(app.ModelAnalysisPanel);
            app.MA_Find_Button = uibutton(app.ModelAnalysisPanel, 'push');
            app.MA_Find_Button.ButtonPushedFcn = createCallbackFcn(app, @MA_Find_ButtonPushed, true);
            app.MA_Find_Button.Text = '찾기';
            app.MA_Analysis_Button = uibutton(app.ModelAnalysisPanel, 'push');
            app.MA_Analysis_Button.ButtonPushedFcn = createCallbackFcn(app, @MA_Analysis_ButtonPushed, true);
            app.MA_Analysis_Button.Text = '분석';
            app.MA_Reset_Button = uibutton(app.ModelAnalysisPanel, 'push');
            app.MA_Reset_Button.ButtonPushedFcn = createCallbackFcn(app, @MA_Reset_ButtonPushed, true);
            app.MA_Reset_Button.Text = 'Reset';
            app.MA_Result_Button = uibutton(app.ModelAnalysisPanel, 'push');
            app.MA_Result_Button.ButtonPushedFcn = createCallbackFcn(app, @MA_Result_ButtonPushed, true);
            app.MA_Result_Button.Text = '분석 결과 확인';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MST_v_1_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end